classdef LBYDISP_MP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        kHzSlider_15k          matlab.ui.control.Slider
        kHzLabel_2             matlab.ui.control.Label
        HzSlider_2_4k          matlab.ui.control.Slider
        kHzLabel               matlab.ui.control.Label
        HzSlider_1k            matlab.ui.control.Slider
        HzLabel_4              matlab.ui.control.Label
        HzSlider_400           matlab.ui.control.Slider
        HzLabel_3              matlab.ui.control.Label
        HzSlider_150           matlab.ui.control.Slider
        HzLabel                matlab.ui.control.Label
        VolumeSlider           matlab.ui.control.Slider
        VolumeSliderLabel      matlab.ui.control.Label
        HzSlider_60            matlab.ui.control.Slider
        HzLabel_2              matlab.ui.control.Label
        UploadAudioFileButton  matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        player
        wave
        audioData
        volumeFactor
        Fs
        filter60Hz = fir1(N, [50 70] / (app.fs / 2), 'bandpass');
        filter150Hz = fir1(N, [100 200] / (app.fs / 2), 'bandpass');
        filter400Hz = fir1(N, [300 500] / (app.fs / 2), 'bandpass');
        filter1kHz = fir1(N, [800 1200] / (app.fs / 2), 'bandpass');
        filter2_4kHz = fir1(N, [2000 5000] / (app.fs / 2), 'bandpass');
        filter15kHz = fir1(N, [10000 20000] / (app.fs / 2), 'bandpass');
    end
    
    methods (Access = private)
        function updateVolumePlot(app, volumeFactor)
            currentAudio = app.audioData * volumeFactor;

            t = (0:length(currentAudio) - 1) / app.player.SampleRate;
            plot(app.UIAxes, t, currentAudio); 
            title(app.UIAxes, 'Audio Signal');
            xlabel(app.UIAxes, 'Time (s)');
        end

        function updateEqualizer(app)
            bandValues = [app.HzSlider_60.Value, app.HzSlider_150.Value, app.HzSlider_400.Value, app.HzSlider_1k.Value, app.HzSlider_2_4k.Value, app.kHzSlider_15k.Value];

            filteredAudio = zeros(size(app.audioData));
            
            for i = 1:size(bandValues)
                filteredAudio = filteredAudio + conv(app.audioData, app.filt60Hz, 'same') * db2mag(app.HzSlider_60Hz.Value);
            end

            currentPosition = app.player.CurrentSample / app.player.SampleRate;
        
            stop(app.player);
            
            % filterd audio 
            app.player = audioplayer(app.audioData * newVolumeFactor, app.player.SampleRate);
        
            play(app.player, round(currentPosition * app.player.SampleRate));

            updateVolumePlot(app, app.VolumeFactor);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadAudioFileButton
        function UploadAudioFileButtonPushed(app, event)
            [f, p] = uigetfile({'*.wav; *.mp3', 'Audio Files (*.wav, *.mp3)'});
            path = fullfile(p, f);
            figure(app.UIFigure);

            [app.audioData, app.Fs] = audioread(path);
            app.player = audioplayer(app.audioData, app.Fs);
            app.VolumeFactor = 1;

            play(app.player);
            t = (0:length(app.audioData) - 1) / app.Fs;

            updateVolumePlot(app, app.VolumeFactor);
        end

        % Value changing function: VolumeSlider
        function VolumeSliderValueChanging(app, event)
            if isempty(app.player)
                return;
            end
            
            app.VolumeFactor = event.Value / 100;  
        
            currentPosition = app.player.CurrentSample / app.player.SampleRate;
        
            stop(app.player);
            
            app.player = audioplayer(app.audioData * newVolumeFactor, app.player.SampleRate);
        
            updateVolumePlot(app, app.VolumeFactor);
            play(app.player, round(currentPosition * app.player.SampleRate));
        end

        % Value changing function: HzSlider_60
        function HzSlider_60ValueChanging(app, event)
            updateEqualizer(app);
        end

        % Value changing function: HzSlider_150
        function HzSlider_150ValueChanging(app, event)
            updateEqualizer(app);
        end

        % Value changing function: HzSlider_400
        function HzSlider_400ValueChanging(app, event)
            updateEqualizer(app);     
        end

        % Value changing function: HzSlider_1k
        function HzSlider_1kValueChanging(app, event)
            updateEqualizer(app);            
        end

        % Value changing function: HzSlider_2_4k
        function HzSlider_2_4kValueChanging(app, event)
            updateEqualizer(app);            
        end

        % Value changing function: kHzSlider_15k
        function kHzSlider_15kValueChanging(app, event)
            updateEqualizer(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Audio Signal')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [-1 -0.9 -0.8 -0.7 -0.6 -0.5 -0.4 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.XTickLabel = {'0.1'; '0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.UIAxes.YTick = [-1 -0.9 -0.8 -0.7 -0.6 -0.5 -0.4 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTickLabel = {'-1'; '-0.9'; '-0.8'; '-0.7'; '-0.6'; '-0.5'; '-0.4'; '-0.3'; '-0.2'; '-0.1'; '0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.UploadAudioFileButton = uibutton(app.UIFigure, 'push');
            app.UploadAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadAudioFileButtonPushed, true);
            app.UploadAudioFileButton.Text = 'Upload Audio File';
            app.HzLabel_2 = uilabel(app.UIFigure);
            app.HzLabel_2.Text = ' 60Hz';
            app.HzSlider_60 = uislider(app.UIFigure);
            app.HzSlider_60.Limits = [-20 20];
            app.HzSlider_60.ValueChangingFcn = createCallbackFcn(app, @HzSlider_60ValueChanging, true);
            app.VolumeSliderLabel = uilabel(app.UIFigure);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.UIFigure);
            app.VolumeSlider.ValueChangingFcn = createCallbackFcn(app, @VolumeSliderValueChanging, true);
            app.VolumeSlider.Value = 50;
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = '150Hz';
            app.HzSlider_150 = uislider(app.UIFigure);
            app.HzSlider_150.Limits = [-20 20];
            app.HzSlider_150.ValueChangingFcn = createCallbackFcn(app, @HzSlider_150ValueChanging, true);
            app.HzLabel_3 = uilabel(app.UIFigure);
            app.HzLabel_3.Text = '400Hz';
            app.HzSlider_400 = uislider(app.UIFigure);
            app.HzSlider_400.Limits = [-20 20];
            app.HzSlider_400.ValueChangingFcn = createCallbackFcn(app, @HzSlider_400ValueChanging, true);
            app.HzLabel_4 = uilabel(app.UIFigure);
            app.HzLabel_4.Text = ' 1kHz';
            app.HzSlider_1k = uislider(app.UIFigure);
            app.HzSlider_1k.Limits = [-20 20];
            app.HzSlider_1k.ValueChangingFcn = createCallbackFcn(app, @HzSlider_1kValueChanging, true);
            app.kHzLabel = uilabel(app.UIFigure);
            app.kHzLabel.Text = '2.4kHz';
            app.HzSlider_2_4k = uislider(app.UIFigure);
            app.HzSlider_2_4k.Limits = [-20 20];
            app.HzSlider_2_4k.ValueChangingFcn = createCallbackFcn(app, @HzSlider_2_4kValueChanging, true);
            app.kHzLabel_2 = uilabel(app.UIFigure);
            app.kHzLabel_2.Text = '15kHz';
            app.kHzSlider_15k = uislider(app.UIFigure);
            app.kHzSlider_15k.Limits = [-20 20];
            app.kHzSlider_15k.ValueChangingFcn = createCallbackFcn(app, @kHzSlider_15kValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LBYDISP_MP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end